classdef aaaaaaaa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ZerosLabel                 matlab.ui.control.Label
        PolosLabel                 matlab.ui.control.Label
        LimparButton               matlab.ui.control.Button
        CalcularButton             matlab.ui.control.Button
        DenominadorEditField       matlab.ui.control.EditField
        DenominadorEditFieldLabel  matlab.ui.control.Label
        NumeradorEditField         matlab.ui.control.EditField
        NumeradorEditFieldLabel    matlab.ui.control.Label
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % Obter entradas do usuário
            Numerador = str2num(app.NumeradorEditField.Value);
            Denominador = str2num(app.DenominadorEditField.Value);
            
            % Verificar se o denominador é zero ou vazio
            if isempty(Denominador) || all(Denominador == 0)
                uialert(app.UIFigure, 'O denominador não pode ser zero ou vazio.', 'Erro');
                return;
            end
            
            % Criar a função de transferência em malha aberta
            sys = tf(Numerador, Denominador);

            % Converter para malha fechada com feedback unitário
            sys_fechado = feedback(sys, 1);

            % Limpar os gráficos
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);

            % Obter polos e zeros do sistema em malha fechada
            [polos, zeros] = pzmap(sys);
                    
            % Exibir polos e zeros nos labels
            app.PolosLabel.Text = ['Polos: ', num2str(polos.')];
            app.ZerosLabel.Text = ['Zeros: ', num2str(zeros.')];

            % Gráfico 1: Lugar Geométrico das Raízes
            rlocus(app.UIAxes, sys);
            title(app.UIAxes, 'Lugar Geométrico das Raízes');

            % Gráfico 2: Resposta ao Degrau
            step(app.UIAxes_2, sys_fechado);
            title(app.UIAxes_2, 'Resposta ao Degrau');

            % Gráfico 3: Resposta a Rampa
            tempo_simulacao = 0:0.01:20; 
            entrada_rampa = tempo_simulacao; 
            [saida_rampa, tempo_resposta] = lsim(sys_fechado, entrada_rampa, tempo_simulacao);
            plot(app.UIAxes_3, tempo_resposta, saida_rampa);
            title(app.UIAxes_3, 'Resposta a Rampa');

            % Gráfico 4: Resposta a Parabola
            entrada_parabola = (tempo_simulacao.^2) / 2;
            [saida_parabola, tempo_resposta] = lsim(sys_fechado, entrada_parabola, tempo_simulacao);
            plot(app.UIAxes_4, tempo_resposta, saida_parabola);
            title(app.UIAxes_4, 'Resposta a Parabola');
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            % Limpar as entradas
            app.NumeradorEditField.Value = '';
            app.DenominadorEditField.Value = '';

            % Limpar os gráficos
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);

            % Redefinir os títulos dos gráficos
            title(app.UIAxes, 'Grafico 1');
            title(app.UIAxes_2, 'Grafico 2');
            title(app.UIAxes_3, 'Grafico 3');
            title(app.UIAxes_4, 'Grafico 4');

            % Limpar labels de polos e zeros
            app.PolosLabel.Text = 'Polos:';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.NumeradorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeradorEditFieldLabel.Text = 'Numerador';
            app.NumeradorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominadorEditFieldLabel = uilabel(app.UIFigure);
            app.DenominadorEditFieldLabel.Text = 'Denominador';
            app.DenominadorEditField = uieditfield(app.UIFigure, 'text');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.PolosLabel = uilabel(app.UIFigure);
            app.PolosLabel.Text = 'Polos';
            app.ZerosLabel = uilabel(app.UIFigure);
            app.ZerosLabel.Text = 'Zeros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aaaaaaaa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end