classdef aaaaaaaaaaa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ZerosLabel                 matlab.ui.control.Label
        PolosLabel                 matlab.ui.control.Label
        LimparButton               matlab.ui.control.Button
        CalcularButton             matlab.ui.control.Button
        SelecioneDropDown          matlab.ui.control.DropDown
        SelecioneDropDownLabel     matlab.ui.control.Label
        DenominadorEditField       matlab.ui.control.EditField
        DenominadorEditFieldLabel  matlab.ui.control.Label
        NumeradorEditField         matlab.ui.control.EditField
        NumeradorEditFieldLabel    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % Obter entradas do usuário
            Numerador = str2num(app.NumeradorEditField.Value);
            Denominador = str2num(app.DenominadorEditField.Value);
            
            % Verificar se o denominador é zero ou vazio
            if isempty(Denominador) || all(Denominador == 0)
                uialert(app.UIFigure, 'O denominador não pode ser zero ou vazio.', 'Erro');
                return;
            end
            
            % Criar a função de transferência
            sys = tf(Numerador, Denominador);

           % Converter para malha fechada com feedback unitário
            sys_fechado = feedback(sys, 1);

            % Limpar o gráfico
            cla(app.UIAxes);
                    
            % Alternar entre as funções selecionadas
            switch app.SelecioneDropDown.Value
                case 'Lugar Geométrico das Raizes'
                    rlocus(app.UIAxes, sys);
                    title(app.UIAxes, 'Lugar Geométrico das Raízes');

                    % Obter polos e zeros do sistema
                    [polos, zeros] = pzmap(sys);

                    % Exibir polos e zeros nos labels
                    app.PolosLabel.Text = ['Polos: ', num2str(polos.')];
                    app.ZerosLabel.Text = ['Zeros: ', num2str(zeros.')];
                    
                case 'Resposta ao Degrau'
                    step(app.UIAxes, sys_fechado);
                    title(app.UIAxes, 'Resposta ao Degrau');

                    % Obter polos e zeros do sistema
                    [polos, zeros] = pzmap(sys_fechado);

                    % Exibir polos e zeros nos labels
                    app.PolosLabel.Text = ['Polos: ', num2str(polos.')];
                    app.ZerosLabel.Text = ['Zeros: ', num2str(zeros.')];
                    
                case 'Resposta a Rampa'
                    % Criar um sistema de rampa
                    tempo_simulacao = 0:0.01:20; 
                    entrada_rampa = tempo_simulacao; 
                    
                    % Simule a resposta do sistema para a entrada rampa
                    [saida_rampa, tempo_resposta] = lsim(sys_fechado, entrada_rampa, tempo_simulacao);
                    
                    % Plote a resposta a rampa no UIAxes
                    plot(app.UIAxes, tempo_resposta, saida_rampa);
                    title(app.UIAxes, 'Resposta a Rampa');

                    % Obter polos e zeros do sistema
                    [polos, zeros] = pzmap(sys_fechado);

                    % Exibir polos e zeros nos labels
                    app.PolosLabel.Text = ['Polos: ', num2str(polos.')];
                    app.ZerosLabel.Text = ['Zeros: ', num2str(zeros.')];

                case 'Resposta a Parabola'
                    % Criar um sistema de parábola
                    tempo_simulacao = 0:0.01:20; 
                    entrada_parabola = (tempo_simulacao.^2) / 2;
                    
                    % Simule a resposta do sistema para a entrada parabólica
                    [saida_parabola, tempo_resposta] = lsim(sys_fechado, entrada_parabola, tempo_simulacao);
                    
                    % Plote a resposta à parábola no UIAxes
                    plot(app.UIAxes, tempo_resposta, saida_parabola);
                    title(app.UIAxes, 'Resposta a Parabola');

                    % Obter polos e zeros do sistema
                    [polos, zeros] = pzmap(sys_fechado);

                    % Exibir polos e zeros nos labels
                    app.PolosLabel.Text = ['Polos: ', num2str(polos.')];
                    app.ZerosLabel.Text = ['Zeros: ', num2str(zeros.')];
            
            end
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            % Limpar as entradas
            app.NumeradorEditField.Value = '';
            app.DenominadorEditField.Value = '';

            % Limpar o gráfico
            cla(app.UIAxes);

            % Redefinir o título do gráfico para "Gráfico"
            title(app.UIAxes, 'Grafico');

            % Limpar labels de polos e zeros
            app.PolosLabel.Text = 'Polos:';
            app.ZerosLabel.Text = 'Zeros:';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NumeradorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeradorEditFieldLabel.Text = 'Numerador';
            app.NumeradorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominadorEditFieldLabel = uilabel(app.UIFigure);
            app.DenominadorEditFieldLabel.Text = 'Denominador';
            app.DenominadorEditField = uieditfield(app.UIFigure, 'text');
            app.SelecioneDropDownLabel = uilabel(app.UIFigure);
            app.SelecioneDropDownLabel.Text = 'Selecione';
            app.SelecioneDropDown = uidropdown(app.UIFigure);
            app.SelecioneDropDown.Items = {'Lugar Geométrico das Raizes', 'Resposta ao Degrau', 'Resposta a Rampa', 'Resposta a Parabola'};
            app.SelecioneDropDown.Value = 'Lugar Geométrico das Raizes';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.PolosLabel = uilabel(app.UIFigure);
            app.PolosLabel.Text = 'Polos';
            app.ZerosLabel = uilabel(app.UIFigure);
            app.ZerosLabel.Text = 'Zeros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aaaaaaaaaaa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end